classdef Beamapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        BEAMCHARCATERSTICSUNDERDIFFERENTLOADINGLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        SelectionTab                    matlab.ui.container.Tab
        ChooseTheTypeofBeamLabel        matlab.ui.control.Label
        CantileverBeamButton            matlab.ui.control.Button
        SimplySupportedBeamButton       matlab.ui.control.Button
        SimplySupportedTab              matlab.ui.container.Tab
        POINTMOMENTSLabel_3             matlab.ui.control.Label
        DISTRIBUTEDLOADSLabel_3         matlab.ui.control.Label
        SIMPLYSUPPORTEDBEAMLabel        matlab.ui.control.Label
        POINTLOADSLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SeeTheFigureButton              matlab.ui.control.Button
        DistancefromLeftSidemEditField  matlab.ui.control.EditField
        DistancefromLeftSidemLabel      matlab.ui.control.Label
        ValueOfPointMomentsEditField    matlab.ui.control.EditField
        ValueOfPointMomentsEditFieldLabel  matlab.ui.control.Label
        EndingPointDistancefromtheLeftmEditField  matlab.ui.control.EditField
        EndingPointDistancefromtheLeftmLabel  matlab.ui.control.Label
        EndingValueofLoadskNEditField   matlab.ui.control.EditField
        EndingValueofLoadskNLabel_2     matlab.ui.control.Label
        StartingPointDistancefromtheLeftmEditField  matlab.ui.control.EditField
        StartingPointDistancefromtheLeftmEditFieldLabel  matlab.ui.control.Label
        StartingValueofLoadskNEditField  matlab.ui.control.EditField
        StartingValueofLoadskNLabel_2   matlab.ui.control.Label
        DistanceFromtheLeftmEditField   matlab.ui.control.EditField
        DistanceFromtheLeftmEditFieldLabel  matlab.ui.control.Label
        ValuesofLoadkNEditField         matlab.ui.control.EditField
        ValuesofLoadkNEditFieldLabel    matlab.ui.control.Label
        EntertheLengthoftheBeamEditField  matlab.ui.control.NumericEditField
        EntertheLengthoftheBeamEditFieldLabel  matlab.ui.control.Label
        CantileverTab                   matlab.ui.container.Tab
        DistancefromLeftSidemEditField_2  matlab.ui.control.EditField
        DistancefromLeftSidemLabel_2    matlab.ui.control.Label
        ValueOfPointMomentsEditField_2  matlab.ui.control.EditField
        ValueOfPointMomentsEditField_2Label  matlab.ui.control.Label
        EndingPointDistancefromtheLeftmEditField_2  matlab.ui.control.EditField
        EndingPointDistancefromtheLeftmEditField_2Label  matlab.ui.control.Label
        EndingValueofLoadskNEditField_2  matlab.ui.control.EditField
        EndingValueofLoadskNLabel       matlab.ui.control.Label
        StartingPointDistancefromtheLeftmEditField_2  matlab.ui.control.EditField
        StartingPointDistancefromtheLeftmEditField_2Label  matlab.ui.control.Label
        StartingValueofLoadskNEditField_2  matlab.ui.control.EditField
        StartingValueofLoadskNLabel     matlab.ui.control.Label
        DistanceFromtheLeftmEditField_2  matlab.ui.control.EditField
        DistanceFromtheLeftmEditField_2Label  matlab.ui.control.Label
        ValuesofLoadkNEditField_2       matlab.ui.control.EditField
        ValuesofLoadkNEditField_2Label  matlab.ui.control.Label
        EntertheLengthoftheBeamEditField_2  matlab.ui.control.NumericEditField
        EntertheLengthoftheBeamEditField_2Label  matlab.ui.control.Label
        CANTILEVERBEAMLabel             matlab.ui.control.Label
        POINTMOMENTSLabel_2             matlab.ui.control.Label
        DISTRIBUTEDLOADSLabel_2         matlab.ui.control.Label
        POINTLOADSLabel_2               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SeetheFigureButton              matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        HowtogiveinputvaluesinaproperwayLabel  matlab.ui.control.Label
        HelpmeImstuckLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.SimplySupportedTab;
        end

        % Button pushed function: SimplySupportedBeamButton
        function SimplySupportedBeamButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.SimplySupportedTab;
        end

        % Button pushed function: CantileverBeamButton
        function CantileverBeamButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.CantileverTab;
        end

        % Button pushed function: SeeTheFigureButton
        function SeeTheFigureButtonPushed(app, event)
             L = app.EntertheLengthoftheBeamEditField.Value;
            n=1000;
            V=zeros(1,n);
             V1=zeros(1,n);
             u=zeros(1,n);
             Z=zeros(1,n);
             xx=(0:n-1)*L/n;
             M=zeros(1,n);
            
           %switch app.BeamtypeDropDown.Value
               
               %case 'Simply Supported Beam'
                       
                    %spoint load
                    %x=app.DISTANCEFROMLEFTSIDE.Value;
                    %P=app.Load.Value;
                    pointloadvector=str2num(app.ValuesofLoadkNEditField.Value) %point load vector ta save hocche
                    pointloaddistancevector=str2num(app.DistanceFromtheLeftmEditField.Value) %point load vector ta save hocche
                    for k=1:length (pointloadvector)

                    [V,u] = forceDiagrams(pointloaddistancevector(k),pointloadvector(k),L,V,u,n);
                     
                    end


                    %Generate Moment Array
                    %M=cumtrapz(xx,V);
                   
                    %plot(xx,V,'Parent',app.UIAxes) ;     %% plots SFD
                    
                    %plot(xx,M,'Parent',app.UIAxes2) ;     %% plots BMD  
                    %plot (xx,u,'Parent',app.UIAxes3);

                        %case 'Distributed Load'
                      xi = str2num(app.StartingPointDistancefromtheLeftmEditField.Value);
                      xf = str2num(app.EndingPointDistancefromtheLeftmEditField.Value);
                      Pi = str2num(app.StartingValueofLoadskNEditField.Value);
                      Pf = str2num(app.EndingValueofLoadskNEditField.Value);


                      for k=1:length (Pi)             %ekta kore hishab korar jonno 

                               xin = ceil(xi(k)*n/L); xfn = ceil(xf(k)*n/L); m = xfn-xin; %no. of iterations
        
                              %Calculations to convert load/meter to load/point
                                Pin = Pi(k)*(xf(k)-xi(k))/m;     Pfn = Pf(k)*(xf(k)-xi(k))/m;
                                 increment = (Pfn-Pin)/m;
                
                                   for i = 1:m
                                      x = xi(k);
                                       [V,u]=forceDiagrams(x,Pin,L,V,u,n);
                                       xi(k)  = xi(k)+L/n;
                                       Pin = Pin + increment;
                                    end
                      end          
                                 % M=cumtrapz(xx,V);
                   
                               % plot(xx,V,'Parent',app.UIAxes)      %% plots SFD
                    
                                %plot(xx,M,'Parent',app.UIAxes2)      %% plots BMD  
                                %plot (xx,u,'Parent',app.UIAxes3)   
                   
                        %point moment

                        Mz    = str2num(app.ValueOfPointMomentsEditField.Value);%input ('Enter moment as array ');
                        x     = str2num(app.DistancefromLeftSidemEditField.Value);%input ('Enter location from left as array ');

                        for k=1:length (x)

                         %Converting moment into force couple
                            P1    = Mz(k)/L;
                         [V,u] = forceDiagrams(x(k),P1,L,V,u,n);
                         [V1,u] = forceDiagrams(x(k),P1,L,V1,u,n);
                             P2    = -Mz(k)/L;
                            x(k)  = x(k)+L/n;
                         [V,u] = forceDiagrams(x(k),P2,L,V,u,n);
                          [V1,u] = forceDiagrams(x(k),P2,L,V1,u,n);
                          Z  = V - V1 + (P1-P1*x(k)/L)*ones(1,n);     %correction


                            end
                        % Generate Moment Array
                        M=M+cumtrapz(xx,V);
                                            
                       %plot(xx,V,'Parent',app.UIAxes)      %% plots SFD
                    
                        %plot(xx,M,'Parent',app.UIAxes2)      %% plots BMD  
                        %plot (xx,u,'Parent',app.UIAxes3) 


                        % Outputs Max values
                V_max = append('Max Sheer = ', int2str(max(Z)),'kN');
                M_max = append('Max Moment = ', int2str(max(M)),'kN-m');
                    % plots SFD
                    subplot(311);
                    %plot(xx,V)
                    %title ('SFD')
                    %xlabel('Distance from left support(m)'),ylabel('Shear force(N)')
                    shade(xx,Z,'-b','Linewidth',2);
                    hold on; grid on;
                    set(gca,'GridLineStyle','--')
                    title ('SFD')
                    xlabel('Distance from left support(m)'),ylabel('Shear force(kN)');
                    legend(V_max)
                    % plots BMD
                    subplot(312);
                    %plot(xx,M)      
                    %title ('BMD')
                    %xlabel('Distance from left support(m)'),ylabel('Bending Moment(N-m)')
                     shade(xx,M,'-b','Linewidth',2);
                    hold on; grid on;
                    set(gca,'GridLineStyle','--');
                    title ('BMD')
                    xlabel('Distance from left support(m)'),ylabel('Bending Moment(kN-m)')
                    legend(M_max)
                    % plots deflection
                    u=u/2;
                    subplot(313);
                    %plot(xx,u)   
                    %title ('Elastic Curve')
                    %xlabel('Distance from left support(m)'),ylabel('Deflection(m)')
                    shade(xx,u,'-b','Linewidth',2);
                    hold on; grid on;
                    set(gca,'GridLineStyle','--');
                    title ('Elastic Curve')
                    xlabel('Distance from left support(m)'),ylabel('Deflection(mm)')
                    u = abs(u);
                    u_max = append('Max Deflection = ', int2str(max(u)),'mm');
                    legend(u_max)
                                    
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.SelectionTab;
        end

        % Button pushed function: SeetheFigureButton
        function SeetheFigureButtonPushed(app, event)
            L = app.EntertheLengthoftheBeamEditField_2.Value;
            n=1000;
            V=zeros(1,n);
             u=zeros(1,n);
             xx=(0:n-1)*L/n;
             M=zeros(1,n);
             V1=zeros(1,n);
             Z=zeros(1,n);
            %if t==2     % Condition for Cantilever
       
            % Point Load Inputs
            P = str2num(app.ValuesofLoadkNEditField_2.Value);            %input ('\n\nEnter all point loads within [ ] separated by spaces: ');
            x = str2num(app.DistanceFromtheLeftmEditField_2.Value);                %input ('Enter coresponding locations similarly: ');

for k=1:length (P)

    [V,u] = forceDiagrams2(x(k),P(k),L,V,u,n);
    M     = M-P(k)*x(k)*ones(1,n);
      
end
% UVL Inputs

Pi = str2num(app.StartingValueofLoadskNEditField_2.Value);%input ('\n\nEnter Starting loads within array: ');
xi = str2num(app.StartingPointDistancefromtheLeftmEditField_2.Value);%input ('Enter Corresponding Starting positions within array: ');
Pf = str2num(app.EndingValueofLoadskNEditField_2.Value);%input ('\nEnter Ending loads within array: ');
xf = str2num(app.EndingPointDistancefromtheLeftmEditField_2.Value)%input ('Enter Corresponding Ending positions within array: ');


for k=1:length (Pi)

    xin = ceil(xi(k)*n/L); xfn = ceil(xf(k)*n/L); m = xfn-xin; %no. of iterations

    %Calculations to convert load/meter to load/point
    Pin = Pi(k)*(xf(k)-xi(k))/m;     Pfn = Pf(k)*(xf(k)-xi(k))/m;
    increment = (Pfn-Pin)/m;
        
    for i     = 1:m
        x     = xi(k);
        [V,u] = forceDiagrams2(x,Pin,L,V,u,n);
        M     = M-Pin*x(k)*ones(1,n);
        xi(k) = xi(k)+L/n;
        Pin   = Pin + increment;            
    end
end

% Point Moments inputs
Mz    = str2num(app.ValueOfPointMomentsEditField_2.Value);%input ('\n\nEnter moment as array: ');
x     = str2num(app.DistancefromLeftSidemEditField_2.Value);%input ('Enter location from left as array: ');

for k=1:length (x)

    %Converting moment into force couple
    P1    = Mz(k)/L;
    [V,u] = forceDiagrams2(x(k),P1,L,V,u,n);
    [V1,u] = forceDiagrams2(x(k),P1,L,V1,u,n);
    P2    = -Mz(k)/L;
    x(k)  = x(k)+L/n;
    [V,u] = forceDiagrams2(x(k),P2,L,V,u,n);
    [V1,u] = forceDiagrams2(x(k),P2,L,V1,u,n);
    M     = M-Mz(k)*ones(1,n);
    Z     = V - V1 + P1*ones(1,n);     %correction

end


% Generate Moment Array
M=M+cumtrapz(xx,V);

% Outputs Max values
V_max = append('Max Sheer = ', int2str(max(Z)),'kN');
M_max = append('Max Moment = ', int2str(max(M)),'kN-m');

% plots SFD
subplot(311);
shade(xx,Z,'-b','Linewidth',2);
hold on; grid on;
set(gca,'GridLineStyle','--')
title ('SFD')
xlabel('Distance from left support(m)'),ylabel('Shear force(kN)');
legend(V_max)

% plots BMD
subplot(312);
shade(xx,M,'-b','Linewidth',2);
hold on; grid on;
set(gca,'GridLineStyle','--');
title ('BMD')
xlabel('Distance from left support(m)'),ylabel('Bending Moment(kN-m)')
legend(M_max)

u=u/2;
% plots deflection
subplot(313);  
shade(xx,u,'-b','Linewidth',2);
hold on; grid on;
set(gca,'GridLineStyle','--');
title ('Elastic Curve')
xlabel('Distance from left support(mm)'),ylabel('Deflection(m)')
u = abs(u);
u_max = append('Max Deflection = ', int2str(max(u)),'mm');
legend(u_max)


        end

        % Button down function: CantileverTab
        function CantileverTabButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image6 = uiimage(app.HomeTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'City Buildings Facebook Cover.png');
            app.StartButton = uibutton(app.HomeTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BEAMCHARCATERSTICSUNDERDIFFERENTLOADINGLabel = uilabel(app.HomeTab);
            app.BEAMCHARCATERSTICSUNDERDIFFERENTLOADINGLabel.Text = 'BEAM CHARCATERSTICS UNDER DIFFERENT LOADING ';
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.SimplySupportedBeamButton = uibutton(app.SelectionTab, 'push');
            app.SimplySupportedBeamButton.ButtonPushedFcn = createCallbackFcn(app, @SimplySupportedBeamButtonPushed, true);
            app.SimplySupportedBeamButton.Text = 'Simply Supported Beam ';
            app.CantileverBeamButton = uibutton(app.SelectionTab, 'push');
            app.CantileverBeamButton.ButtonPushedFcn = createCallbackFcn(app, @CantileverBeamButtonPushed, true);
            app.CantileverBeamButton.Text = 'Cantilever Beam';
            app.ChooseTheTypeofBeamLabel = uilabel(app.SelectionTab);
            app.ChooseTheTypeofBeamLabel.Text = 'Choose The Type of Beam';
            app.SimplySupportedTab = uitab(app.TabGroup);
            app.SimplySupportedTab.Title = 'Simply Supported';
            app.EntertheLengthoftheBeamEditFieldLabel = uilabel(app.SimplySupportedTab);
            app.EntertheLengthoftheBeamEditFieldLabel.Text = 'Enter the Length of the Beam ';
            app.EntertheLengthoftheBeamEditField = uieditfield(app.SimplySupportedTab, 'numeric');
            app.EntertheLengthoftheBeamEditField.ValueDisplayFormat = '%12.4g';
            app.ValuesofLoadkNEditFieldLabel = uilabel(app.SimplySupportedTab);
            app.ValuesofLoadkNEditFieldLabel.Text = 'Values of Load (kN)';
            app.ValuesofLoadkNEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.DistanceFromtheLeftmEditFieldLabel = uilabel(app.SimplySupportedTab);
            app.DistanceFromtheLeftmEditFieldLabel.Text = 'Distance From the Left(m)';
            app.DistanceFromtheLeftmEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.StartingValueofLoadskNLabel_2 = uilabel(app.SimplySupportedTab);
            app.StartingValueofLoadskNLabel_2.Text = 'Starting Value of Loads (kN)';
            app.StartingValueofLoadskNEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.StartingPointDistancefromtheLeftmEditFieldLabel = uilabel(app.SimplySupportedTab);
            app.StartingPointDistancefromtheLeftmEditFieldLabel.Text = 'Starting Point Distance from the Left (m)';
            app.StartingPointDistancefromtheLeftmEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.EndingValueofLoadskNLabel_2 = uilabel(app.SimplySupportedTab);
            app.EndingValueofLoadskNLabel_2.Text = 'Ending Value of Loads (kN)';
            app.EndingValueofLoadskNEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.EndingPointDistancefromtheLeftmLabel = uilabel(app.SimplySupportedTab);
            app.EndingPointDistancefromtheLeftmLabel.Text = 'Ending Point Distance from the Left (m)';
            app.EndingPointDistancefromtheLeftmEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.ValueOfPointMomentsEditFieldLabel = uilabel(app.SimplySupportedTab);
            app.ValueOfPointMomentsEditFieldLabel.Text = 'Value Of Point Moments ';
            app.ValueOfPointMomentsEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.DistancefromLeftSidemLabel = uilabel(app.SimplySupportedTab);
            app.DistancefromLeftSidemLabel.Text = 'Distance from Left Side (m)';
            app.DistancefromLeftSidemEditField = uieditfield(app.SimplySupportedTab, 'text');
            app.SeeTheFigureButton = uibutton(app.SimplySupportedTab, 'push');
            app.SeeTheFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SeeTheFigureButtonPushed, true);
            app.SeeTheFigureButton.Text = 'See The Figure';
            app.Label = uilabel(app.SimplySupportedTab);
            app.Label.Text = '**IF YOU WANT TO  PUT MORE THAN ONE LOAD IN EVERY SECTION THEN MAKE A SPACE BETWEEN THE TWO INPUT VALUES**';
            app.POINTLOADSLabel = uilabel(app.SimplySupportedTab);
            app.POINTLOADSLabel.Text = 'POINT LOADS';
            app.SIMPLYSUPPORTEDBEAMLabel = uilabel(app.SimplySupportedTab);
            app.SIMPLYSUPPORTEDBEAMLabel.Text = 'SIMPLY SUPPORTED BEAM';
            app.DISTRIBUTEDLOADSLabel_3 = uilabel(app.SimplySupportedTab);
            app.DISTRIBUTEDLOADSLabel_3.Text = 'DISTRIBUTED LOADS';
            app.POINTMOMENTSLabel_3 = uilabel(app.SimplySupportedTab);
            app.POINTMOMENTSLabel_3.Text = 'POINT MOMENTS ';
            app.CantileverTab = uitab(app.TabGroup);
            app.CantileverTab.Title = 'Cantilever';
            app.CantileverTab.ButtonDownFcn = createCallbackFcn(app, @CantileverTabButtonDown, true);
            app.SeetheFigureButton = uibutton(app.CantileverTab, 'push');
            app.SeetheFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SeetheFigureButtonPushed, true);
            app.SeetheFigureButton.Text = 'See the Figure';
            app.Label_2 = uilabel(app.CantileverTab);
            app.Label_2.Text = '**IF YOU WANT TO  PUT MORE THAN ONE LOAD IN EVERY SECTION THEN MAKE A SPACE BETWEEN THE TWO INPUT VALUES**';
            app.POINTLOADSLabel_2 = uilabel(app.CantileverTab);
            app.POINTLOADSLabel_2.Text = 'POINT LOADS';
            app.DISTRIBUTEDLOADSLabel_2 = uilabel(app.CantileverTab);
            app.DISTRIBUTEDLOADSLabel_2.Text = 'DISTRIBUTED LOADS';
            app.POINTMOMENTSLabel_2 = uilabel(app.CantileverTab);
            app.POINTMOMENTSLabel_2.Text = 'POINT MOMENTS ';
            app.CANTILEVERBEAMLabel = uilabel(app.CantileverTab);
            app.CANTILEVERBEAMLabel.Text = 'CANTILEVER BEAM';
            app.EntertheLengthoftheBeamEditField_2Label = uilabel(app.CantileverTab);
            app.EntertheLengthoftheBeamEditField_2Label.Text = 'Enter the Length of the Beam ';
            app.EntertheLengthoftheBeamEditField_2 = uieditfield(app.CantileverTab, 'numeric');
            app.EntertheLengthoftheBeamEditField_2.ValueDisplayFormat = '%12.4g';
            app.ValuesofLoadkNEditField_2Label = uilabel(app.CantileverTab);
            app.ValuesofLoadkNEditField_2Label.Text = 'Values of Load (kN)';
            app.ValuesofLoadkNEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.DistanceFromtheLeftmEditField_2Label = uilabel(app.CantileverTab);
            app.DistanceFromtheLeftmEditField_2Label.Text = 'Distance From the Left (m)';
            app.DistanceFromtheLeftmEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.StartingValueofLoadskNLabel = uilabel(app.CantileverTab);
            app.StartingValueofLoadskNLabel.Text = 'Starting Value of Loads (kN)';
            app.StartingValueofLoadskNEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.StartingPointDistancefromtheLeftmEditField_2Label = uilabel(app.CantileverTab);
            app.StartingPointDistancefromtheLeftmEditField_2Label.Text = 'Starting Point Distance from the Left (m)';
            app.StartingPointDistancefromtheLeftmEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.EndingValueofLoadskNLabel = uilabel(app.CantileverTab);
            app.EndingValueofLoadskNLabel.Text = 'Ending Value of Loads (kN)';
            app.EndingValueofLoadskNEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.EndingPointDistancefromtheLeftmEditField_2Label = uilabel(app.CantileverTab);
            app.EndingPointDistancefromtheLeftmEditField_2Label.Text = 'Ending Point Distance from the Left (m)';
            app.EndingPointDistancefromtheLeftmEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.ValueOfPointMomentsEditField_2Label = uilabel(app.CantileverTab);
            app.ValueOfPointMomentsEditField_2Label.Text = 'Value Of Point Moments ';
            app.ValueOfPointMomentsEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.DistancefromLeftSidemLabel_2 = uilabel(app.CantileverTab);
            app.DistancefromLeftSidemLabel_2.Text = 'Distance from Left Side (m)';
            app.DistancefromLeftSidemEditField_2 = uieditfield(app.CantileverTab, 'text');
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpmeImstuckLabel = uilabel(app.HelpTab);
            app.HelpmeImstuckLabel.Text = 'Help me, I''m stuck!';
            app.HowtogiveinputvaluesinaproperwayLabel = uilabel(app.HelpTab);
            app.HowtogiveinputvaluesinaproperwayLabel.Text = 'How to give input values in a proper way';
            app.Image4 = uiimage(app.HelpTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '7.jpg');
            app.Image5 = uiimage(app.HelpTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'what-to-do-in-Alanya.jpg');
            app.Hyperlink = uihyperlink(app.HelpTab);
            app.Hyperlink.URL = 'https://l.facebook.com/l.php?u=https%3A%2F%2Fyoutu.be%2FXufUSx3gsXA%3Ffbclid%3DIwAR0utH-x6o2hJferWatY8zpIlnfekFXTvWzccIQ47XMV2iFXcELlHL1Y9qE&h=AT0RsyTIKrly7pp7mlCqTnWk5KmQ9v5LF6_hDE2H_xChE9jF6qNwqFa2YLF72D8LoeXSgyoJ7DzjZ3mBzcts6Xya1nRKKr2M3qrvihCbiJMbqxlUQYsm-3K-PGw2uUau9V62ug';
            app.Hyperlink.Text = 'Click Here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Beamapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end